classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        ArmonicosEditField       matlab.ui.control.NumericEditField
        ArmonicosEditFieldLabel  matlab.ui.control.Label
        AmplitudEditField        matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel   matlab.ui.control.Label
        error                    matlab.ui.control.Label
        PerodoEditField          matlab.ui.control.NumericEditField
        PerodoEditFieldLabel     matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        sealesDropDown           matlab.ui.control.DropDown
        sealesDropDownLabel      matlab.ui.control.Label
        CenterPanel              matlab.ui.container.Panel
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
    y
    T
    A
    n
    t
    op
    end
    
    methods (Access = private)
        
        function plotear(app)
            app.T= app.PerodoEditField.Value;
            app.A=app.AmplitudEditField.Value;
            app.n=app.ArmonicosEditField.Value;
            app.UIAxes2.set("Visible",false);
            if app.T==0 || app.A==0
                app.Button.set("Visible",false);
                app.error.set("Visible",true);
            else
                app.error.set("Visible",false);
                app.T=app.PerodoEditField.Value;
                app.t=-app.T:0.01:app.T;
                if app.op=="Seno"
                    app.y=abs(sin(2*(pi/app.T)*app.t));
                elseif app.op=="Cuadrada"
                    app.y=square(2*(pi/app.T)*app.t);
                elseif app.op=="Rampa"
                    delta=0.01;
                    app.T=round(app.T,2);
                    app.t=-app.T:delta:app.T;
                    l=length(app.t);
                    if mod(app.T,3)==0
                        y1=linspace(0,1,length((-app.T:1:l/3-2)));
                        y2=ones(1,length(l/3:1:(2/3)*l-1));
                        y3=linspace(1,0,length(((2/3)*l+1:1:l-1)));
                    elseif mod(app.T,4)==0 || mod(app.T,3)==1 || app.T==1
                        y1=linspace(0,1,length((-app.T:1:l/3-2)));
                        y2=ones(1,length(l/3:1:(2/3)*l-1));
                        y3=linspace(1,0,length((2/3)*l:1:l-1));
                    elseif round(app.T)~=app.T
                        y1=linspace(0,1,length((-app.T:1:l/3-1)));
                        y2=ones(1,length(l/3:1:(2/3)*l-1));
                        y3=linspace(1,0,length((2/3)*l+1:1:l-1));   
                    else
                        y1=linspace(0,1,length((-app.T:1:l/3-1)));
                        y2=ones(1,length(l/3:1:(2/3)*l-1));
                        y3=linspace(1,0,length((2/3)*l:1:l-1));
                    end
                    app.y=[y1 y2 y3];
                elseif app.op=="Triangular"
                    app.y=sawtooth(2*(pi/app.T)*app.t);
                elseif app.op=="Exponencial"
                    app.y=exp(app.t*2*pi/app.T);
                end
                app.y=app.y*app.A;
                if app.op~="Exponencial" || app.op~="Seno"
                    ylim(app.UIAxes,[-1*app.A*1.05,app.A*01.05]);
                    xlim(app.UIAxes,[-app.T,app.T])
                else
                    ylim(app.UIAxes,[-0.05,app.A*01.05]);
                    xlim(app.UIAxes,[-app.T,app.T])  
                end
                plot(app.UIAxes,app.t,app.y);
                app.Button.set("Visible",true);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: sealesDropDown
        function sealesDropDownValueChanged(app, event)
            app.op = app.sealesDropDown.Value;
            if app.op~="--"
                app.PerodoEditField.set("Visible",true);
                app.PerodoEditFieldLabel.set("Visible",true);
                app.AmplitudEditField.set("Visible",true);
                app.AmplitudEditFieldLabel.set("Visible",true);
                app.ArmonicosEditField.set("Visible",true);
                app.ArmonicosEditFieldLabel.set("Visible",true);
                plotear(app)
            else
                app.PerodoEditField.set("Visible",false);
                app.PerodoEditFieldLabel.set("Visible",false);
                app.UIAxes2.set("Visible",false);
                app.UIAxes3.set("Visible",false);
                app.UIAxes4.set("Visible",false);
                app.error.set("Visible",false);
                app.Button.set("Visible",false);
                app.AmplitudEditField.set("Visible",false);
                app.AmplitudEditFieldLabel.set("Visible",false);
                app.ArmonicosEditField.set("Visible",false);
                app.ArmonicosEditFieldLabel.set("Visible",false);
            end
        end

        % Value changed function: PerodoEditField
        function PerodoEditFieldValueChanged(app, event)
            plotear(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes2.set("Visible",true);
            app.UIAxes3.set("Visible",true);
            app.UIAxes4.set("Visible",true);
            wo=2*pi/app.T;
            a0=0;
            app.n=app.ArmonicosEditField.Value;
            ak=zeros(1,app.n);
            bk=zeros(1,app.n);
            ck=zeros(1,app.n);
            phik=zeros(1,app.n);
            m=length(app.t);
            dt=0.01;
            nw0=1:app.n;
            for i=1:m
                a0=a0+(1/app.T)*app.y(i)*dt;
            end
            disp((1/app.T)*app.y(1)*dt)
            for i=1:app.n
                for j=1:m
                    ak(i)=ak(i)+((2/app.T)*app.y(j)*cos(i*app.t(j)*wo))*dt;
                    bk(i)=bk(i)+((2/app.T)*app.y(j)*sin(i*app.t(j)*wo))*dt;
                end
                ck(1,i)=((ak(i)^2*bk(i)^2))^0.5;
                phik(1,i)=-atan(bk(i)/ak(i));
            end
            t1=-2*app.T:dt:2*app.T;
            xf=ones(1,length(t1)).*a0;
            for i=1:1:app.n
                xf=xf+ak(i)*cos(i*wo*t1)+bk(i)*sin(i*wo*t1);
            end
            stem(app.UIAxes3,nw0,ck);
            title(app.UIAxes3,"Espectro de amplitud");
            stem(app.UIAxes4,nw0,phik);
            title(app.UIAxes4,"Espectro de fase");
            plot(app.UIAxes2,t1,xf);
        end

        % Value changed function: AmplitudEditField
        function AmplitudEditFieldValueChanged(app, event)
            plotear(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 12};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.sealesDropDownLabel = uilabel(app.LeftPanel);
            app.sealesDropDownLabel.Text = 'señales ';
            app.sealesDropDown = uidropdown(app.LeftPanel);
            app.sealesDropDown.Items = {'--', 'Seno', 'Cuadrada', 'Rampa', 'Triangular', 'Exponencial'};
            app.sealesDropDown.ValueChangedFcn = createCallbackFcn(app, @sealesDropDownValueChanged, true);
            app.sealesDropDown.Value = '--';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Visible = 'off';
            app.PerodoEditFieldLabel = uilabel(app.LeftPanel);
            app.PerodoEditFieldLabel.Visible = 'off';
            app.PerodoEditFieldLabel.Text = 'Período ';
            app.PerodoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PerodoEditField.ValueChangedFcn = createCallbackFcn(app, @PerodoEditFieldValueChanged, true);
            app.PerodoEditField.Visible = 'off';
            app.error = uilabel(app.LeftPanel);
            app.error.Visible = 'off';
            app.error.Text = 'Por favor digite un numero diferente de 0 para los valores e amplitud y periodo';
            app.AmplitudEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudEditFieldLabel.Visible = 'off';
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.AmplitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudEditFieldValueChanged, true);
            app.AmplitudEditField.Visible = 'off';
            app.ArmonicosEditFieldLabel = uilabel(app.LeftPanel);
            app.ArmonicosEditFieldLabel.Visible = 'off';
            app.ArmonicosEditFieldLabel.Text = '#Armonicos';
            app.ArmonicosEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ArmonicosEditField.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.CenterPanel);
            app.UIAxes2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.CenterPanel);
            app.UIAxes3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end