classdef prototipo1_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Image                         matlab.ui.control.Image
        multi                         matlab.ui.control.UIAxes
        conv                          matlab.ui.control.UIAxes
        x_kp                          matlab.ui.control.UIAxes
        h_kp                          matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        amph                          matlab.ui.control.NumericEditField
        AmplitudEditField_2Label      matlab.ui.control.Label
        pufh                          matlab.ui.control.NumericEditField
        PuntofinalEditField_2Label    matlab.ui.control.Label
        puih                          matlab.ui.control.NumericEditField
        PuntoinicialEditField_2Label  matlab.ui.control.Label
        ampx                          matlab.ui.control.NumericEditField
        AmplitudEditFieldLabel        matlab.ui.control.Label
        Pufx                          matlab.ui.control.NumericEditField
        PuntofinalEditFieldLabel      matlab.ui.control.Label
        puix                          matlab.ui.control.NumericEditField
        PuntoinicialEditFieldLabel    matlab.ui.control.Label
        GraficarButton                matlab.ui.control.Button
        menuH                         matlab.ui.control.DropDown
        HDropDownLabel                matlab.ui.control.Label
        menuX                         matlab.ui.control.DropDown
        XDropDownLabel                matlab.ui.control.Label
        DominioButtonGroup            matlab.ui.container.ButtonGroup
        dis                           matlab.ui.control.RadioButton
        con                           matlab.ui.control.RadioButton
        ht                            matlab.ui.control.UIAxes
        xt                            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: menuX
        function menuXValueChanged(app, event)
      
        end

        % Callback function
        function pixValueChanged(app, event)
            
        end

        % Value changed function: puix
        function puixValueChanged(app, event)

        end

        % Button down function: x_kp
        function x_kpButtonDown(app, event)

        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
            cla(app.xt,"reset");
            cla(app.ht,"reset");
            cla(app.x_kp,"reset");
            cla(app.h_kp,"reset");
            cla(app.multi,"reset");
            cla(app.conv,"reset");
            clear hconv;
                    vc=app.con.Value;
                    vd=app.dis.Value;
                    if vc==1
                        dom=2;
                        delta=0.0001;
                    elseif vd==1
                        dom=1;
                        delta=1;
                    end
            pix=app.puix.Value;
            pfx=app.Pufx.Value;
            Ax=app.ampx.Value;
            lx=pfx-pix+1;
            tx=pix:delta:pfx;
            pih=app.puih.Value;
            pfh=app.pufh.Value;
            Ah=app.amph.Value;
            lh=pfh-pih+1;
            th=pih:delta:pfh;
            opx=app.menuX.Value;
                    if opx== "Triangular"
                       xcon=1;
                    elseif opx== "Cuadrada"
                        xcon=2;
                    elseif opx=="Seno"
                        xcon=3;
                    elseif opx=="Exponencial"
                        xcon=4;
                    elseif opx=="Rampa 1"
                        xcon=5;
                    elseif opx=="Rampa 2"
                        xcon=6;
                    elseif opx=="Rampa 3"
                        xcon=7;
                    end
            oph=app.menuH.Value;
                    if oph=="Triangular"
                       hconv=1;
                    elseif oph=="Cuadrada"
                        hconv=2;
                    elseif oph=="Seno"
                        hconv=3;
                    elseif oph=="Exponencial"
                        hconv=4;
                    elseif opx=="Rampa 1"
                        hconv=5;
                    elseif opx=="Rampa 2"
                        hconv=6;
                    elseif opx=="Rampa 3"
                        hconv=7;
                    end
%--------- Señal triangular-----------------
if xcon==1 || hconv==1
    if dom==1 && xcon==1
        x=Ax*tripuls(tx-(pix+pfx)/2,pfx-pix,0);
        stem(app.xt,tx,x)
        title(app.xt,"x[n]")
    elseif dom==2 && xcon==1
        x=Ax*tripuls(tx-(pix+pfx)/2,pfx-pix,0);
        plot(app.xt,tx,x)
        title(app.xt,"x(t)")
    end
    if dom==1 && hconv==1
        h=Ah*tripuls(th-(pih+pfh)/2,pfh-pih,0);
        stem(app.ht,th,h)
        title(app.ht,"h[n]")
    elseif dom==2 && hconv==1
        h=Ah*tripuls(th-(pih+pfh)/2,pfh-pih,0);
        plot(app.ht,th,h)
        title(app.ht,"h(t)")
    end
end
%----------Señal cuadrada----------------
if xcon==2 || hconv==2
    if xcon==2
        x=Ax*(ones(1,length(tx)));
    end
    if hconv==2
        h=Ah*(ones(1,length(th)));
    end
    if dom==1 && xcon==2
        tc=pix-delta:delta:(pfx+delta);
        xc=[zeros(1,1) x zeros(1,1)];
        stem(app.xt,tc,xc)
        title(app.xt,"x[n]")
    elseif dom==2 && xcon==2
        tc=pix-delta:delta:(pfx+delta);
        xc=[zeros(1,1) x zeros(1,1)];
        plot(app.xt,tc,xc)
        title(app.xt,"x(t)")
    end
     if dom==1 && hconv==2
        tc=pih-delta:delta:(pfh+delta);
        hc=[zeros(1,1) h zeros(1,1)];
        stem(app.ht,tc,hc)
        title(app.ht,"h[n]")
    elseif dom==2 && hconv==2
        tc=pih-delta:delta:(pfh+delta);
        hc=[zeros(1,1) h zeros(1,1)];
        plot(app.ht,tc,hc)
        title(app.ht,"h(t)")
    end
end
%----------Sinuidal-------------------
if xcon==3 || hconv==3
    if dom==1 && xcon==3
        f=1/(pfx-pix);
        x=sin(2*pi*f*tx)*Ax;
        stem(app.xt,tx,x)
        title(app.xt,"x[n]")
    elseif dom==2 && xcon==3
        f=1/(pfx-pix);
        x=sin(2*pi*f*tx)*Ax;
        plot(app.xt,tx,x)
        title(app.xt,"x(t)")
    end
    if dom==1 && hconv==3
        f=1/(pfh-pih);
        h=sin(2*pi*f*th)*Ah;
        stem(app.ht,th,h)
        title(app.ht,"h[n]")
    elseif dom==2 && hconv==3
        f=1/(pfh-pih);
        h=sin(2*pi*f*th)*Ah;
        plot(app.ht,th,h)
        title(app.ht,"h(t)")
    end
end
%---------------exponencial----------
if xcon==4 || hconv==4
    if dom==1 && xcon==4
        x=exp(tx*Ax);
        stem(app.xt,tx,x)
        title(app.xt,"x[n]")
    elseif dom==2 && xcon==4
        x=exp(tx*Ax);
        plot(app.xt,tx,x)
        title(app.xt,"x(t)")
    end
    if dom==1 && hconv==4
        h=exp(th*Ah);
        stem(app.ht,th,h)
        title(app.ht,"h[n]")
    elseif dom==2 && hconv==4
        h=exp(th*Ah);
        plot(app.ht,th,h)
        title(app.ht,"h(t)")
    end
end
%----------Rampa 1--------------------------
    if dom==1 && xcon==5
        largo=pfx-pix+1;
        x1=pix:delta:(round(largo/3,2));
        x2=round(largo/3,2)+delta:delta:round(largo*(2/3),2);
        x3=round(largo*(2/3),2)+delta:delta:pfx-delta;
        y1=ones(1,(length(x1)+1))*0;
        % m=y2-y1/x2-x1
        i1=find(tx==(round(largo*(1/3),2)));
        i2=find(tx==(round(largo*(2/3),2)));
        m=(amplitud)/round(((i2-i1)*delta),2);
        m=round(m,2);
        %amplitud=mt+b
        %-b=amplitud-mt
        b=amplitud-m*(round(n(i2),2));
        p1=m.*x2+b;
        y2=ones(1,length(x2)).*p1;
        y3=ones(1,length(x3))*amplitud;
        x=[y1 y2 y3];
        stem(app.ht,tx,x)
        title(app.ht,"h[n]")
    elseif dom==2 && xcon==5
        largo=pfx-pix+1;
        x1=pix:delta:(round(largo/3,2));
        x2=round(largo/3,2)+delta:delta:round(largo*(2/3),2);
        x3=round(largo*(2/3),2)+delta:delta:pfx-delta;
        y1=ones(1,(length(x1)+1))*0;
        % m=y2-y1/x2-x1
        i1=find(tx==(round(largo*(1/3),2)));
        i2=find(tx==(round(largo*(2/3),2)));
        m=(Ax)/round(((i2-i1)*delta),2);
        m=round(m,2);
        %amplitud=mt+b
        %-b=amplitud-mt
        b=Ax-m*(round(n(i2),2));
        p1=m.*x2+b;
        y2=ones(1,length(x2)).*p1;
        y3=ones(1,length(x3))*Ax;
        h=[y1 y2 y3];
        plot(app.ht,th,h)
        title(app.ht,"h(t)")
    end
    if dom==1 && hconv==5
        largo=pfh-pih+1;
        h1=pix:delta:(round(largo/3,2));
        h2=round(largo/3,2)+delta:delta:round(largo*(2/3),2);
        h3=round(largo*(2/3),2)+delta:delta:pfh-delta;
        y1=ones(1,(length(h1)+1))*0;
        % m=y2-y1/x2-x1
        i1=find(th==(round(largo*(1/3),2)));
        i2=find(th==(round(largo*(2/3),2)));
        m=(amplitud)/round(((i2-i1)*delta),2);
        m=round(m,2);
        %amplitud=mt+b
        %-b=amplitud-mt
        b=amplitud-m*(round(n(i2),2));
        p1=m.*h2+b;
        y2=ones(1,length(h2)).*p1;
        y3=ones(1,length(h3))*amplitud;
        h=[y1 y2 y3];
        stem(app.ht,th,h)
        title(app.ht,"h[n]")
    elseif dom==2 && hconv==5
        largo=pfh-pih+1;
        h1=pix:delta:(round(largo/3,2));
        h2=round(largo/3,2)+delta:delta:round(largo*(2/3),2);
        h3=round(largo*(2/3),2)+delta:delta:pfh-delta;
        y1=ones(1,(length(h1)+1))*0;
        % m=y2-y1/x2-x1
        i1=find(th==(round(largo*(1/3),2)));
        i2=find(th==(round(largo*(2/3),2)));
        m=(amplitud)/round(((i2-i1)*delta),2);
        m=round(m,2);
        %amplitud=mt+b
        %-b=amplitud-mt
        b=amplitud-m*(round(n(i2),2));
        p1=m.*h2+b;
        y2=ones(1,length(h2)).*p1;
        y3=ones(1,length(h3))*amplitud;
        h=[y1 y2 y3];
        plot(app.ht,th,h)
        title(app.ht,"h(t)")
    end
eje=pix-lh:delta:pfx+lh;
set(app.xt,'visible','on')
set(app.ht,'visible','on')
set(app.x_kp,'visible','on')
set(app.multi,'visible','on')
set(app.h_kp,'visible','on')
set(app.conv,'visible','on')
lt=lh+lx;
if dom==1
    for i=0:lx+lh-2
        pause(0.5)
        %----defino la señal x en terminos de K o Tao
        x_k=[zeros(1,lh*1/delta) x zeros(1,lh*1/delta)];
        stem(app.x_kp,eje,x_k)
        title(app.x_kp,"x[k]")
        %------------defino h-k ----------------------------------
        h_menosk=[zeros(1,(i+1)/delta) h(end:-1:1) zeros(1,(lx+lh-1-i)/delta)];
        stem(app.h_kp,eje,h_menosk)
        title(app.h_kp,"h[n-k]")
        %-----------defino la multiplicacion de x_k y h n-k ----------
        stem(app.multi,eje,(x_k.*h_menosk));
        title(app.multi,"x[k]h[n-k]")
        xlabel(app.multi,"k")
        %-----------defino la convolucion------------
        y(i+1)=sum(x_k.*h_menosk);
        stem(app.conv,(pix+pih):(pfx+pfh),[y zeros(1,lx+lh-2-i)])
        title(app.conv,"y[n]")
    end
elseif dom==2
        larges=lx-lh;
        for i=0:length(larges)
        pause(0.5)
        %----defino la señal x en terminos de K o Tao
        x_k=[zeros(1,lh*1/delta) x zeros(1,lh*1/delta)];
        plot(app.x_kp,eje,x_k)
        title(app.x_kp,"x[k]")
        %------------defino h-k ----------------------------------
        h_menosk=[zeros(1,(i+1)/delta) h(end:-1:1) zeros(1,(lx+lh-1-i)/delta)];
        plot(app.h_kp,eje,h_menosk)
        title(app.h_kp,"h[n-k]")
        %-----------defino la multiplicacion de x_k y h n-k ----------
        plot(app.multi,eje,(x_k.*h_menosk));
        title(app.multi,"x[k]h[n-k]")
        xlabel(app.multi,"k")
        %-----------defino la convolucion------------
        y(i+1)=sum(x_k.*h_menosk);
        plot(app.conv,(pix+pih):(pfx+pfh),[y zeros(1,lx+lh-2-i)])
        title(app.conv,"y[n]")
        end
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.xt = uiaxes(app.LeftPanel);
            zlabel(app.xt, 'Z')
            app.xt.Visible = 'off';
            app.ht = uiaxes(app.LeftPanel);
            app.ht.Visible = 'off';
            app.DominioButtonGroup = uibuttongroup(app.LeftPanel);
            app.DominioButtonGroup.Title = 'Dominio';
            app.con = uiradiobutton(app.DominioButtonGroup);
            app.con.Text = 'Continuo';
            app.con.Value = true;
            app.dis = uiradiobutton(app.DominioButtonGroup);
            app.dis.Text = 'Discreto';
            app.XDropDownLabel = uilabel(app.LeftPanel);
            app.XDropDownLabel.Text = 'X';
            app.menuX = uidropdown(app.LeftPanel);
            app.menuX.Items = {'Triangular', 'Cuadrada', 'Seno', 'Exponencial', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.menuX.ValueChangedFcn = createCallbackFcn(app, @menuXValueChanged, true);
            app.menuX.Value = 'Triangular';
            app.HDropDownLabel = uilabel(app.LeftPanel);
            app.HDropDownLabel.Text = 'H';
            app.menuH = uidropdown(app.LeftPanel);
            app.menuH.Items = {'Triangular', 'Cuadrada', 'Exponencial decreciente', 'Exponencial creciente', 'Rampa 1', 'Rampa 2', 'Rampa 3'};
            app.menuH.Value = 'Triangular';
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.PuntoinicialEditFieldLabel = uilabel(app.LeftPanel);
            app.PuntoinicialEditFieldLabel.Text = 'Punto inicial';
            app.puix = uieditfield(app.LeftPanel, 'numeric');
            app.puix.ValueChangedFcn = createCallbackFcn(app, @puixValueChanged, true);
            app.PuntofinalEditFieldLabel = uilabel(app.LeftPanel);
            app.PuntofinalEditFieldLabel.Text = 'Punto final';
            app.Pufx = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditFieldLabel = uilabel(app.LeftPanel);
            app.AmplitudEditFieldLabel.Text = 'Amplitud';
            app.ampx = uieditfield(app.LeftPanel, 'numeric');
            app.PuntoinicialEditField_2Label = uilabel(app.LeftPanel);
            app.PuntoinicialEditField_2Label.Text = 'Punto inicial';
            app.puih = uieditfield(app.LeftPanel, 'numeric');
            app.PuntofinalEditField_2Label = uilabel(app.LeftPanel);
            app.PuntofinalEditField_2Label.Text = 'Punto final';
            app.pufh = uieditfield(app.LeftPanel, 'numeric');
            app.AmplitudEditField_2Label = uilabel(app.LeftPanel);
            app.AmplitudEditField_2Label.Text = 'Amplitud';
            app.amph = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.h_kp = uiaxes(app.GridLayout2);
            zlabel(app.h_kp, 'Z')
            app.h_kp.Visible = 'off';
            app.x_kp = uiaxes(app.GridLayout2);
            zlabel(app.x_kp, 'Z')
            app.x_kp.ButtonDownFcn = createCallbackFcn(app, @x_kpButtonDown, true);
            app.x_kp.Visible = 'off';
            app.conv = uiaxes(app.GridLayout2);
            app.conv.Visible = 'off';
            app.multi = uiaxes(app.GridLayout2);
            zlabel(app.multi, 'Z')
            app.multi.Visible = 'off';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo-UNINORTE.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototipo1_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end